classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        time      matlab.ui.control.TextArea
        Label_5   matlab.ui.control.Label
        accuracy  matlab.ui.control.TextArea
        Label_6   matlab.ui.control.Label
        level     matlab.ui.control.TextArea
        Label_4   matlab.ui.control.Label
        disease   matlab.ui.control.TextArea
        Label_3   matlab.ui.control.Label
        fruit     matlab.ui.control.TextArea
        Label_2   matlab.ui.control.Label
        exit      matlab.ui.control.StateButton
        clean     matlab.ui.control.StateButton
        Button    matlab.ui.control.StateButton
        Label     matlab.ui.control.Label
        Image     matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.ImageSource = imresize(imread("东南校徽.jpg"),[224,224]);
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            [app.fruit.Value,app.disease.Value,app.level.Value,app.accuracy.Value,app.time.Value,app.Image.ImageSource] = Deal()
        end

        % Image clicked function: Image
        function ImageClicked(app, event)

        end

        % Value changed function: clean
        function cleanValueChanged(app, event)
            app.fruit.Value = "";
            app.disease.Value = "";
            app.level.Value = "";
            app.accuracy.Value = "";
            app.time.Value = "";
            app.Image.ImageSource = imresize(imread("东南校徽.jpg"),[224,224]);
        end

        % Value changed function: exit
        function exitValueChanged(app, event)
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '果树病虫害识别系统';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '开始识别';
            app.clean = uibutton(app.UIFigure, 'state');
            app.clean.ValueChangedFcn = createCallbackFcn(app, @cleanValueChanged, true);
            app.clean.Text = '清除';
            app.exit = uibutton(app.UIFigure, 'state');
            app.exit.ValueChangedFcn = createCallbackFcn(app, @exitValueChanged, true);
            app.exit.Text = '退出';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '果树名称';
            app.fruit = uitextarea(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '病害名称';
            app.disease = uitextarea(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '严重程度';
            app.level = uitextarea(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '检测精度';
            app.accuracy = uitextarea(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '检测耗时';
            app.time = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end